classdef MathLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        SpeciesTab                 matlab.ui.container.Tab
        SpeciesStatusLabel         matlab.ui.control.Label
        SpeciesPropsTable          matlab.ui.control.Table
        SpeciesListBox             matlab.ui.control.ListBox
        RemoveSpeciesButton        matlab.ui.control.Button
        AddSpeciesButton           matlab.ui.control.Button
        SpeciesNameEditField       matlab.ui.control.EditField
        SpeciesNameEditFieldLabel  matlab.ui.control.Label
        StreamsTab                 matlab.ui.container.Tab
        UITable                    matlab.ui.control.Table
        RemoveStreamButton         matlab.ui.control.Button
        AddStreamButton            matlab.ui.control.Button
        StreamsTable               matlab.ui.control.Table
        RunTab                     matlab.ui.container.Tab
        RunButton                  matlab.ui.control.Button
        LogsTab                    matlab.ui.container.Tab
        LogTextArea                matlab.ui.control.TextArea
        TextAreaLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        species cell = {'H2','O2','H2O'}   % for now fixed
        streams cell = {}                 % cell array of proc.Stream handles
        units   cell = {}                 % cell array of proc.units.* handles (later)
    
        fs                              % proc.Flowsheet built at run
        lastSolver                      % proc.ProcessSolver from last run
        % Species database for this session/app:
        % Key: species string, Value: struct of properties
        speciesDB containers.Map
    end
    
    methods (Access = private)
        
            function addStream(app, name)
            s = proc.Stream(string(name), app.species);
    
            % Safe defaults for guesses
            s.n_dot = 1;
            s.T = 300;
            s.P = 1e5;
            s.y = ones(1,numel(app.species))/numel(app.species);
    
            % Ensure unknown by default
            s.known.n_dot = false;
            s.known.T = false;
            s.known.P = false;
            s.known.y(:) = false;
    
            app.streams{end+1} = s;
        end
    
        function refreshStreamsTable(app)
            ns = numel(app.species);
            N  = numel(app.streams);
    
            names = strings(N,1);
            nd = nan(N,1); TT = nan(N,1); PP = nan(N,1);
            Y  = nan(N,ns);
    
            for i = 1:N
                s = app.streams{i};
                names(i) = string(s.name);
                nd(i) = s.n_dot;
                TT(i) = s.T;
                PP(i) = s.P;
                Y(i,:) = s.y(:).';
            end
    
            T = table(names, nd, TT, PP);
            for j = 1:ns
                T.(sprintf("y_%s", app.species{j})) = Y(:,j);
            end
    
            app.StreamsTable.Data = T;
            app.StreamsTable.ColumnEditable = true(1,width(T));
        end
    
        function pushStreamsTableToModel(app)
            D  = app.StreamsTable.Data;
            ns = numel(app.species);
    
            for i = 1:height(D)
                s = app.streams{i};
                s.n_dot = D.nd(i);
                s.T     = D.TT(i);
                s.P     = D.PP(i);
    
                yy = zeros(1,ns);
                for j = 1:ns
                    yy(j) = D.(sprintf("y_%s", app.species{j}))(i);
                end
                s.y = yy;
            end
        end
    
        function runSolve(app)
            % Update model from table edits
            app.pushStreamsTableToModel();
    
            % Build flowsheet (for now: no units, just show streams)
            fs = proc.Flowsheet(app.species);
            for i = 1:numel(app.streams)
                fs.addStream(app.streams{i});
            end
    
            % TODO: add units when we add a Units UI
            for i = 1:numel(app.units)
                fs.addUnit(app.units{i});
            end
    
            try
                solver = fs.solve('maxIter',80,'tolAbs',1e-9,'printToConsole',false);
                app.lastSolver = solver;
    
                app.LogTextArea.Value = cellstr(solver.logLines);
                app.ResultsTable.Data = fs.streamTable();
    
            catch ME
                app.LogTextArea.Value = [{ME.message}; app.LogTextArea.Value];
                uialert(app.UIFigure, ME.message, "Solve failed");
            end
        end

        function rec = defaultSpeciesRecord(app, name)
            % Minimal record now; extend later with Shomate/NASA etc.
            rec = struct();
            rec.name = char(name);
        
            % Placeholders (unknown until linked to library or user fills)
            rec.MW = NaN;          % kg/kmol or g/mol (you choose standard later)
            rec.CpModel = "none";  % "shomate", "nasa", "const", etc.
            rec.CpParams = [];     % store coefficients if applicable
            rec.notes = "";
            rec.source = "manual"; % "library" when found
        end
        
        function refreshSpeciesUI(app)
            % Update listbox
            app.SpeciesListBox.Items = app.species;
            if isempty(app.species)
                app.SpeciesListBox.Items = {};
            end
        
            % Build property table from speciesDB in global order
            N = numel(app.species);
            names  = strings(N,1);
            MW     = nan(N,1);
            source = strings(N,1);
            notes  = strings(N,1);
        
            for i = 1:N
                nm = app.species{i};
                names(i) = string(nm);
        
                if isKey(app.speciesDB, char(nm))
                    rec = app.speciesDB(char(nm));
                    MW(i) = rec.MW;
                    source(i) = string(rec.source);
                    notes(i) = string(rec.notes);
                else
                    % shouldn't happen, but keep robust
                    MW(i) = NaN;
                    source(i) = "missing";
                    notes(i) = "";
                end
            end
        
            T = table(names, MW, source, notes);
            app.SpeciesPropsTable.Data = T;
            app.SpeciesPropsTable.ColumnEditable = [false true false true]; % allow MW + notes edits
        end
        
        function [rec, found] = tryLibraryLookup(app, nm)
            % FUTURE:
            % - Search your species library by name
            % - Populate MW, CpModel, CpParams, source="library"
            %
            % For now: always not found.
            found = false;
            rec = struct();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                % Create a default stream S1
                app.addStream("S1");

                % Initialize species DB
                app.speciesDB = containers.Map('KeyType','char','ValueType','any');

                % Seed defaults (for now minimal placeholders)
                for i = 1:numel(app.species)
                    nm = app.species{i};
                    app.speciesDB(char(nm)) = app.defaultSpeciesRecord(nm);
                end
                        
                % Set S1 as a feed (example)
                S1 = app.streams{1};
                S1.n_dot = 10; S1.T = 300; S1.P = 1e5; S1.y = [0.8 0.2 0];
                S1.known.n_dot = true;
                S1.known.T = true;
                S1.known.P = true;
                S1.known.y(:) = true;
            
                app.refreshStreamsTable();
                app.refreshSpeciesUI();
                app.SpeciesStatusLabel.Text = "Ready. Add species like CH4. (Library hookup later)";
        end

        % Button pushed function: AddStreamButton
        function AddStreamButtonPushed(app, event)
                name = "S" + string(numel(app.streams) + 1);
                app.addStream(name);
                app.refreshStreamsTable();
        end

        % Button pushed function: RemoveStreamButton
        function RemoveStreamButtonPushed(app, event)
                sel = app.StreamsTable.Selection;
                if isempty(sel), return; end
                row = sel(1);
            
                if row >= 1 && row <= numel(app.streams)
                    app.streams(row) = [];
                    app.refreshStreamsTable();
                end
        end

        % Cell edit callback: StreamsTable
        function StreamsTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.pushStreamsTableToModel();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
                app.runSolve();
        end

        % Button pushed function: AddSpeciesButton
        function AddSpeciesButtonPushed(app, event)
            nm = strtrim(app.SpeciesNameEditField.Value);

    if nm == ""
        app.SpeciesStatusLabel.Text = "Enter a species name first.";
        return;
    end

    % Normalise: keep as char/cellstr
    nmChar = char(nm);

    % Prevent duplicates
    if any(strcmpi(app.species, nmChar))
        app.SpeciesStatusLabel.Text = "Species already in list.";
        return;
    end

    % Attempt lookup in future library (stub for now)
    [rec, found] = app.tryLibraryLookup(nmChar);

    if ~found
        rec = app.defaultSpeciesRecord(nmChar);
        rec.source = "manual";
        app.SpeciesStatusLabel.Text = "Species not found in library. Added as manual.";
    else
        rec.source = "library";
        app.SpeciesStatusLabel.Text = "Species found in library and added.";
    end

    % Add to global list + DB
    app.species{end+1} = nmChar;
    app.speciesDB(nmChar) = rec;

    app.refreshSpeciesUI();
    app.SpeciesNameEditField.Value = "";
        end

        % Button pushed function: RemoveSpeciesButton
        function RemoveSpeciesButtonPushed(app, event)
            sel = app.SpeciesListBox.Value;

    if isempty(sel)
        app.SpeciesStatusLabel.Text = "Select a species to remove.";
        return;
    end

    selChar = char(sel);

    % Remove from list
    keep = ~strcmp(app.species, selChar);
    app.species = app.species(keep);

    % Remove from DB
    if isKey(app.speciesDB, selChar)
        remove(app.speciesDB, selChar);
    end

    app.refreshSpeciesUI();
    app.SpeciesStatusLabel.Text = "Removed species: " + string(selChar);
        end

        % Cell edit callback: SpeciesPropsTable
        function SpeciesPropsTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
                D = app.SpeciesPropsTable.Data;

    % Identify which row changed
    row = event.Indices(1);
    col = event.Indices(2);

    if row < 1 || row > height(D)
        return;
    end

    nm = char(D.names(row));
    if ~isKey(app.speciesDB, nm)
        return;
    end

    rec = app.speciesDB(nm);

    % Column mapping: names, MW, source, notes
    if col == 2
        rec.MW = D.MW(row);
        rec.source = "manual"; % user has edited
    elseif col == 4
        rec.notes = char(D.notes(row));
    end

    app.speciesDB(nm) = rec;
    app.SpeciesStatusLabel.Text = "Updated properties for " + string(nm);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SpeciesTab = uitab(app.TabGroup);
            app.SpeciesTab.Title = 'Species';
            app.SpeciesNameEditFieldLabel = uilabel(app.SpeciesTab);
            app.SpeciesNameEditFieldLabel.Text = 'Species Name';
            app.SpeciesNameEditField = uieditfield(app.SpeciesTab, 'text');
            app.AddSpeciesButton = uibutton(app.SpeciesTab, 'push');
            app.AddSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @AddSpeciesButtonPushed, true);
            app.AddSpeciesButton.Text = 'Add Species';
            app.RemoveSpeciesButton = uibutton(app.SpeciesTab, 'push');
            app.RemoveSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSpeciesButtonPushed, true);
            app.RemoveSpeciesButton.Text = 'Remove Selected';
            app.SpeciesListBox = uilistbox(app.SpeciesTab);
            app.SpeciesPropsTable = uitable(app.SpeciesTab);
            app.SpeciesPropsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SpeciesPropsTable.RowName = {};
            app.SpeciesPropsTable.CellEditCallback = createCallbackFcn(app, @SpeciesPropsTableCellEdit, true);
            app.SpeciesStatusLabel = uilabel(app.SpeciesTab);
            app.StreamsTab = uitab(app.TabGroup);
            app.StreamsTab.Title = 'Streams';
            app.StreamsTable = uitable(app.StreamsTab);
            app.StreamsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.StreamsTable.RowName = {};
            app.StreamsTable.CellEditCallback = createCallbackFcn(app, @StreamsTableCellEdit, true);
            app.AddStreamButton = uibutton(app.StreamsTab, 'push');
            app.AddStreamButton.ButtonPushedFcn = createCallbackFcn(app, @AddStreamButtonPushed, true);
            app.AddStreamButton.Text = 'Add Stream';
            app.RemoveStreamButton = uibutton(app.StreamsTab, 'push');
            app.RemoveStreamButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveStreamButtonPushed, true);
            app.RemoveStreamButton.Text = 'Remove Stream';
            app.UITable = uitable(app.StreamsTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.RunButton = uibutton(app.RunTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run Solver';
            app.LogsTab = uitab(app.TabGroup);
            app.LogsTab.Title = 'Logs';
            app.TextAreaLabel = uilabel(app.LogsTab);
            app.TextAreaLabel.Text = 'Text Area';
            app.LogTextArea = uitextarea(app.LogsTab);
            app.LogTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MathLab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end