classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup2               matlab.ui.container.TabGroup
        HomeTab_2               matlab.ui.container.Tab
        StatusLabel_2           matlab.ui.control.Label
        ApplySpeciesButton_2    matlab.ui.control.Button
        SpeciesTextArea_2       matlab.ui.control.TextArea
        SpeciesTextArea_2Label  matlab.ui.control.Label
        FlowsheetTab_2          matlab.ui.container.Tab
        UITable                 matlab.ui.control.Table
        RunResultsTab           matlab.ui.container.Tab
    end

    
    properties (Access = public)
        TabGroup matlab.ui.container.TabGroup

        HomeTab matlab.ui.container.Tab
        SpeciesTextArea matlab.ui.control.TextArea
        ApplySpeciesButton matlab.ui.control.Button
        StatusLabel matlab.ui.control.Label

        FlowsheetTab matlab.ui.container.Tab
        StreamsTable matlab.ui.control.Table
        AddStreamButton matlab.ui.control.Button
        RemoveStreamButton matlab.ui.control.Button

        UnitsTable matlab.ui.control.Table
        AddUnitDropDown matlab.ui.control.DropDown
        AddUnitButton matlab.ui.control.Button
        RemoveUnitButton matlab.ui.control.Button

        RunTab matlab.ui.container.Tab
        RunButton matlab.ui.control.Button
        ConsoleStrideDropDown matlab.ui.control.DropDown
        LogTextArea matlab.ui.control.TextArea
        ResultsTable matlab.ui.control.Table
    end
    
    properties (Access = private)
        species cell = {'H2','O2','H2O'}
        streams cell = {}     % proc.Stream handles
        units   cell = {}     % proc.units.* handles

        fs proc.Flowsheet     % built at run-time
        lastSolver            % ProcessSolver (returned by fs.solve, if you modify Flowsheet)
    end
    
    methods (Access = private)
        
         function startup(app)
            % initial UI state
            app.SpeciesTextArea.Value = app.species(:);
            app.StatusLabel.Text = "Ready. Set species, then build flowsheet.";
            app.AddUnitDropDown.Items = {'Mixer','Link','Reactor','Separator','Purge'};
            app.ConsoleStrideDropDown.Items = {'Off','Every 1','Every 5','Every 10'};
            app.ConsoleStrideDropDown.Value = 'Off';

            % Create a default feed stream
            app.addStream("S1");
            app.streams{1}.n_dot = 10;
            app.streams{1}.T = 300;
            app.streams{1}.P = 1e5;
            app.streams{1}.y = [0.8 0.2 0];

            % Lock feed by default (assuming Stream initializes known.*)
            app.streams{1}.known.n_dot = true;
            app.streams{1}.known.T = true;
            app.streams{1}.known.P = true;
            app.streams{1}.known.y(:) = true;

            app.refreshTables();
        end

        function applySpecies(app)
            vals = strtrim(app.SpeciesTextArea.Value);
            vals = vals(~cellfun(@isempty, vals));
            if isempty(vals)
                uialert(app.UIFigure, "Species list cannot be empty.", "Invalid input");
                return;
            end

            app.species = vals(:).';
            % Reset model because stream y length depends on species
            app.streams = {};
            app.units = {};
            app.lastSolver = [];
            app.StatusLabel.Text = "Species applied. Streams/units cleared. Add streams and units.";
            app.addStream("S1");
            app.refreshTables();
        end

        function addStream(app, name)
            s = proc.Stream(string(name), app.species);
            % Provide decent defaults as guesses
            s.n_dot = 1;
            s.T = 300;
            s.P = 1e5;
            s.y = ones(1,numel(app.species))/numel(app.species);
            app.streams{end+1} = s;
        end

        function removeSelectedStream(app)
            idx = app.StreamsTable.Selection;
            if isempty(idx), return; end
            row = idx(1);
            if row >= 1 && row <= numel(app.streams)
                app.streams(row) = [];
                app.refreshTables();
            end
        end

        function addUnit(app)
            typ = app.AddUnitDropDown.Value;

            % You add a unit as a "placeholder" with default connections, then edit later
            if numel(app.streams) < 2
                uialert(app.UIFigure, "Add at least 2 streams first.", "Not enough streams");
                return;
            end

            S1 = app.streams{1};
            S2 = app.streams{min(2,end)};

            switch typ
                case 'Mixer'
                    u = proc.units.Mixer({S1}, S2);

                case 'Link'
                    u = proc.units.Link(S1, S2);

                case 'Reactor'
                    rxn.reactants = [1 2];
                    rxn.products  = [3];
                    rxn.stoich    = [-2 -1 2];
                    rxn.name      = "Default";
                    u = proc.units.Reactor(S1, S2, rxn, 0.5);

                case 'Separator'
                    ns = numel(app.species);
                    phi = ones(1,ns)/ns;
                    % placeholder: outletA=S1, outletB=S2 (you’ll change)
                    u = proc.units.Separator(S1, S1, S2, phi);

                case 'Purge'
                    % placeholder: in=S1, recycle=S2, purge=S1
                    u = proc.units.Purge(S1, S2, S1, 0.9);
            end

            app.units{end+1} = u;
            app.refreshTables();
        end

        function removeSelectedUnit(app)
            idx = app.UnitsTable.Selection;
            if isempty(idx), return; end
            row = idx(1);
            if row >= 1 && row <= numel(app.units)
                app.units(row) = [];
                app.refreshTables();
            end
        end

        function refreshTables(app)
            % Streams table
            ns = numel(app.species);
            N = numel(app.streams);

            names = strings(N,1);
            nd = nan(N,1); TT = nan(N,1); PP = nan(N,1);
            Y = nan(N,ns);

            for i = 1:N
                s = app.streams{i};
                names(i) = string(s.name);
                nd(i) = s.n_dot; TT(i)=s.T; PP(i)=s.P;
                if numel(s.y)==ns
                    Y(i,:) = s.y;
                end
            end

            T = table(names, nd, TT, PP);
            for j=1:ns
                T.(sprintf("y_%s", app.species{j})) = Y(:,j);
            end

            app.StreamsTable.Data = T;
            app.StreamsTable.ColumnEditable = true(1,width(T));

            % Units table (simple listing)
            U = numel(app.units);
            uType = strings(U,1);
            for k = 1:U
                uType(k) = string(class(app.units{k}));
            end
            app.UnitsTable.Data = table(uType);
        end

        function updateStreamsFromTable(app)
            % Push edited stream table values back into the Stream objects
            if isempty(app.StreamsTable.Data), return; end
            D = app.StreamsTable.Data;
            ns = numel(app.species);

            for i = 1:height(D)
                s = app.streams{i};

                s.n_dot = D.nd(i);
                s.T     = D.TT(i);
                s.P     = D.PP(i);

                yy = zeros(1,ns);
                for j = 1:ns
                    yy(j) = D.(sprintf("y_%s", app.species{j}))(i);
                end
                s.y = yy;
            end
        end

        function [fs] = buildFlowsheet(app)
            fs = proc.Flowsheet(app.species);
            for i=1:numel(app.streams), fs.addStream(app.streams{i}); end
            for i=1:numel(app.units),   fs.addUnit(app.units{i}); end
        end

        function runSimulation(app)
            app.updateStreamsFromTable();

            % Build flowsheet and solve
            fs = app.buildFlowsheet();

            % console stride settings
            strideVal = app.ConsoleStrideDropDown.Value;
            printToConsole = ~strcmp(strideVal,'Off');
            stride = 10;
            if strcmp(strideVal,'Every 1'), stride = 1; end
            if strcmp(strideVal,'Every 5'), stride = 5; end
            if strcmp(strideVal,'Every 10'), stride = 10; end

            try
                solver = fs.solve('maxIter', 80, 'tolAbs', 1e-9, 'printToConsole', printToConsole, 'consoleStride', stride);
                app.lastSolver = solver;
                app.LogTextArea.Value = cellstr(solver.logLines);
                app.ResultsTable.Data = fs.streamTable();
                app.StatusLabel.Text = "Solve finished.";
            catch ME
                app.StatusLabel.Text = "Solve failed.";
                app.LogTextArea.Value = [{ME.message}; cellstr(app.LogTextArea.Value)];
                rethrow(ME);
            end
        end
    end


    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.HomeTab_2 = uitab(app.TabGroup2);
            app.HomeTab_2.Title = 'Home';
            app.SpeciesTextArea_2Label = uilabel(app.HomeTab_2);
            app.SpeciesTextArea_2Label.Text = 'SpeciesTextArea';
            app.SpeciesTextArea_2 = uitextarea(app.HomeTab_2);
            app.ApplySpeciesButton_2 = uibutton(app.HomeTab_2, 'push');
            app.ApplySpeciesButton_2.Text = 'ApplySpeciesButton';
            app.StatusLabel_2 = uilabel(app.HomeTab_2);
            app.StatusLabel_2.Text = 'StatusLabel';
            app.FlowsheetTab_2 = uitab(app.TabGroup2);
            app.FlowsheetTab_2.Title = 'Flowsheet';
            app.UITable = uitable(app.FlowsheetTab_2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.RunResultsTab = uitab(app.TabGroup2);
            app.RunResultsTab.Title = 'Run/Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end